classdef ZBR_Pawel_Lekan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DTab                           matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        WSPMASZYNOWETab                matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        UIAxes2                        matlab.ui.control.UIAxes
        Tab                            matlab.ui.container.Tab
        Tab2                           matlab.ui.container.Tab
        Tab3                           matlab.ui.container.Tab
        L1EditFieldLabel               matlab.ui.control.Label
        L1EditField                    matlab.ui.control.NumericEditField
        L2EditFieldLabel               matlab.ui.control.Label
        L2EditField                    matlab.ui.control.NumericEditField
        L3EditFieldLabel               matlab.ui.control.Label
        L3EditField                    matlab.ui.control.NumericEditField
        eEditFieldLabel                matlab.ui.control.Label
        eEditField                     matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        dEditField                     matlab.ui.control.NumericEditField
        L4EditFieldLabel               matlab.ui.control.Label
        L4EditField                    matlab.ui.control.NumericEditField
        L5EditFieldLabel               matlab.ui.control.Label
        L5EditField                    matlab.ui.control.NumericEditField
        L6EditFieldLabel               matlab.ui.control.Label
        L6EditField                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        EditField_3                    matlab.ui.control.EditField
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        XEditField_2Label              matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        YEditField_2                   matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        ZEditField_2                   matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.EditField
        stEditField_2Label             matlab.ui.control.Label
        stEditField_2                  matlab.ui.control.NumericEditField
        stEditFieldLabel               matlab.ui.control.Label
        stEditField                    matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        Label_2                        matlab.ui.control.Label
        Switch_2                       matlab.ui.control.Switch
        Label_3                        matlab.ui.control.Label
        Switch_3                       matlab.ui.control.Switch
        EditField_5                    matlab.ui.control.EditField
        Spinner                        matlab.ui.control.Spinner
        ROZPOCZNIJButton               matlab.ui.control.StateButton
        STANLampLabel                  matlab.ui.control.Label
        STANLamp                       matlab.ui.control.Lamp
        DODAWANIEPUNKTWPOREDNICHPanel  matlab.ui.container.Panel
        XEditField_3Label              matlab.ui.control.Label
        XEditField_3                   matlab.ui.control.NumericEditField
        YEditField_3Label              matlab.ui.control.Label
        YEditField_3                   matlab.ui.control.NumericEditField
        ZEditField_3Label              matlab.ui.control.Label
        ZEditField_3                   matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.EditField
        DODAJButton                    matlab.ui.control.Button
        USUButton                      matlab.ui.control.Button
        Spinner_2                      matlab.ui.control.Spinner
        EditField_8                    matlab.ui.control.EditField
        UITable2                       matlab.ui.control.Table
        EditField_7                    matlab.ui.control.EditField
        InformacjeTextAreaLabel        matlab.ui.control.Label
        InformacjeTextArea             matlab.ui.control.TextArea
        OdstepczasowypomiecykolejnymipozycjamiSpinnerLabel  matlab.ui.control.Label
        OdstepczasowypomiecykolejnymipozycjamiSpinner  matlab.ui.control.Spinner
        CzypokazatrajektorieSwitchLabel  matlab.ui.control.Label
        CzypokazatrajektorieSwitch     matlab.ui.control.Switch
        Switch_4                       matlab.ui.control.Switch
    end

    
    properties (Access = private)
        l_pkt_pos=0;                 
        p_posX=zeros(1, 5);          
        p_posY=zeros(1, 5);
        p_posZ=zeros(1, 5);
        
        tab2
        tab3
        
        xpts;
        ypts;
        zpts;
        extra_pts=0
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ROZPOCZNIJButton
        function ROZPOCZNIJButtonValueChanged(app, event)
            value = app.ROZPOCZNIJButton.Value;
            
            x0 = 0;
            y0 = 0;
            z0 = 0;
            
            
            %wprowadzenie zmiennych z interfacu
            %
            %
            %
            
            
            podp = app.Spinner.Value
            total_points = podp + app.extra_pts
            if podp < 1
                podp = 1
            end
            l1 = app.L1EditField.Value;
            l2 = app.L2EditField.Value;
            l3 = app.L3EditField.Value;
            l4 = app.L4EditField.Value;
            l5 = app.L5EditField.Value;
            l6 = app.L6EditField.Value;
            e = app.eEditField.Value
            d = app.dEditField.Value
            theta = app.stEditField_2.Value
            psi = app.stEditField.Value
            
            xt = app.XEditField.Value;
            yt = app.YEditField.Value;
            zt = app.ZEditField.Value;
            
            xf = app.XEditField_2.Value
            yf = app.YEditField_2.Value
            zf = app.ZEditField_2.Value
            
            app.xpts(1)=xt;
            app.ypts(1)=yt;
            app.zpts(1)=zt;
            
            app.xpts(total_points+2)=xf;
            app.ypts(total_points+2)=yf;
            app.zpts(total_points+2)=zf;
            
            
            x_diff = (xf-xt)/total_points
            y_diff = (yf-yt)/total_points
            z_diff = (zf-zt)/total_points
            
            tab = zeros(total_points+1,5)
            % koniec wyliczania 
            %
            %
            
            %%%%%%%%%%%%%%%%%%%%%% odpala sie kiedy mamy punkty posrednie


            %%%%%%%%%%%%%%%%%%%%%%
            
            %wyliczanie wspolrzednych maszynowych
            for i = 1:total_points+2


                theta = theta*pi/180
                psi = psi*pi/180
                
                delta1 = app.Switch.Value
                if delta1 == '-1'
                    delta1 = -1
                else
                    delta1 = 1
                end
                
                delta5 = app.Switch_2.Value
                if delta5 == '-1'
                    delta5 = -1
                else
                    delta5 = 1
                end
                
                delta2 = app.Switch_3.Value
                if delta2 == '-1'
                    delta2 = -1
                else
                    delta2 = 1
                end
                
                xp = xt - (l5+l6).*cos(theta).*cos(psi)
                yp = yt - (l5+l6).*cos(theta).*sin(psi)
                zp = zt - (l5+l6).*sin(theta)
                
                S1 = 1./(xp.^2+yp.^2).*(e.*xp+delta1.*yp.*sqrt(xp.^2+yp.^2-e.^2))
                C1 = 1./(xp.^2+yp.^2).*(-e.*yp+delta1.*xp.*sqrt(xp.^2+yp.^2-e^2))
                
                S5 = cos(theta).*(sin(psi).*C1-cos(psi).*S1)
                C5 = sqrt(1-S5.^2)
                
                S234 = sin(theta)./C5
                C234 = cos(theta)./C5.*(cos(psi).*C1 + sin(psi).*S1)
                
                xr = xp - l4.*C1.*C234
                yr = yp - l4.*S1.*C234
                zr = zp - l4.*S234
                
                a = -l1 + delta1.*sqrt(xr.^2+yr.^2-e.^2)
                b = 1./2./l2.*(a.^2+zr.^2+l2.^2-l3.^2)
                
                S2 = 1./(a.^2+zr.^2).*(zr.*b+delta2.*a.*sqrt(a.^2+zr.^2-b.^2))
                C2 = 1./(a.^2+zr.^2).*(a.*b-delta2.*zr.*sqrt(a.^2+zr.^2-b.^2))
                
                S3 = 1./l3.*(zr.*C2-a.*S2)
                C3 = 1./l3.*(a.*C2+zr.*S2-l2)
                
                S23 = 1./l3.*(zr-l2.*S2)
                C23 = 1./l3.*(a-l2.*C2)
                
                S4 = S234.*C23 - C234.*S23
                C4 = C234.*C23 + S234.*S23
                %fi1
                if abs(S1) > abs(C1)
                    fi1 = acos(C1)
                else
                    fi1 = asin(S1)
                end
                fi1 = fi1.*180/pi
                %fi2
                if abs(S2) > abs(C2)
                    fi2 = acos(C2)
                else
                    fi2 = asin(S2)
                end
                fi2 = fi2.*180/pi
                %fi3
                if abs(S3) > abs(C3)
                    fi3 = acos(C3)
                else
                    fi3 = asin(S3)
                end
                fi3 = fi3.*180/pi
                %fi4
                if abs(S4) > abs(C4)
                    fi4 = acos(C4)
                else
                    fi4 = asin(S4)
                end
                fi4 = fi4.*180/pi
                %fi5
                if abs(S5) > abs(C5)
                    fi5 = acos(C5)
                else
                    fi5 = asin(S5)
                end
                fi5 = fi5.*180/pi
                %koniec wyznaczania wspolrzednych maszynowych
                
                %%%%%%%%%%%%%%%%%%%%%%Obliczenia sprawdzające i pomocnicze
                x01 = l1.*C1;
                y01 = l1.*S1;
                z01 = 0;
                
                x001 = x01 + d.*S1;
                y001 = y01 - d.*C1;
                z001 = 0;
                
                x002 = x001 + l2.*C2.*C1;
                y002 = y001 + l2.*C2.*S1;
                z002 = l2.*S2;
                
                x02 = x002 - (d-e).*S1;
                y02 = y002 + (d-e).*C1;
                z02 = z002;
                
                xrspr = x02 + l3.*C1.*C23;
                yrspr = y02 + l3.*S1.*C23;
                zrspr = z02 + l3.*S23;
                
                xpspr = xrspr + l4.*C1.*C234;
                ypspr = yrspr + l4.*S1.*C234;
                zpspr = zrspr + l4.*S234;
                
                xtspr = xpspr + (l5+l6).*cos(2 .* pi .* theta / 360).*cos(2 .* pi .* psi / 360);
                ytspr = ypspr + (l5+l6).*cos(2 .* pi .* theta / 360).*sin(2 .* pi .* psi / 360);
                ztspr = zpspr + (l5+l6).*sin(2 .* pi .* theta / 360);
                %%%%%%%%%%%%%%%Trzeba sprawdzić czy indeksy spr są takie same jak ich
                %%%%%%%%%%%%%%%odpowiedniki bez i dadać jakieś gówno w interfejsie które
                %%%%%%%%%%%%%%%będzie wyświelało ewentualne błędy
                
                %%%%%%%%%%%%%%%%%%%%%% Koniec obliczenia sprawdzające i pomocnicze
                %%%%%%%%%%%%%%%%%%%%%% W tej petli trzeba jeszcze dodać zeby na
                %%%%%%%%%%%%%%%%%%%%%% biezaco rysowaol na plocie te punkty


                %Zmienna przechowuje rozmiar wektora wspolrzednych
                pom=ones(1, total_points+2);
            
                x01 = x01 .*pom;
                y01 = y01 .* pom;
                z01 = z01 .* pom;
                
                x001 = x001 .* pom;
                y001 = y001 .*pom;
                z001 = z001 .* pom;
                
                x002 = x002 .* pom;
                y002 = y002 .* pom;
                z002 = z002 .* pom;
                
                x02 = x02 .* pom;
                y02 = y02 .* pom;
                z02 = z02 .* pom;
                
                xr = xr .* pom;
                yr = yr .* pom;
                zr = zr .* pom;
                
                x0 = x0 .* pom;
                y0 = y0 .* pom;
                z0 = z0 .* pom;
                
                xp = xp .* pom;
                yp = yp .* pom;
                zp = zp .* pom;
                
                xt = xt .* pom;
                yt = yt .* pom;
                zt = zt .* pom;
                
                %Sprawdzenie czy trajektorię da się wykonać
            if(imag(x0+x01+x001+x002+x02+xr+xp+xt+y0+y01+y001+y002+y02+yr+yp+yt+z0+z01+z001+z002+z02+zr+zp+zt)==0)
                %Macierz współrzędnych maszynowych
                app.InformacjeTextArea.Value =('Wybrana trajektoria ruchu TCP jest możliwa do osiagniecia!');
                for j=1:total_points+2   
                    x=[x0(j) x01(j) x001(j) x002(j) x02(j) xr(j) xp(j) xt(j)];
                    y=[y0(j) y01(j) y001(j) y002(j) y02(j) yr(j) yp(j) yt(j)];
                    z=[z0(j) z01(j) z001(j) z002(j) z02(j) zr(j) zp(j) zt(j)];
                    
                    if app.Switch_4.Value=="Pokaz pozycje pokolei"
                        pause(app.OdstepczasowypomiecykolejnymipozycjamiSpinner.Value/10)
                    end
                    
                    plot3(app.UIAxes,[x(1) x(2)],[y(1) y(2)],[z(1) z(2)],'-*');
                    hold(app.UIAxes,'on')        %funkcja "hold" utrzymuje początek powyższego wektora w zerze
                    plot3(app.UIAxes,[x(2) x(3)],[y(2) y(3)],[z(2) z(3)],'-x');
                    plot3(app.UIAxes,[x(3) x(4)],[y(3) y(4)],[z(3) z(4)],'-*');
                    plot3(app.UIAxes,[x(4) x(5)],[y(4) y(5)],[z(4) z(5)],'-x');
                    plot3(app.UIAxes,[x(5) x(6)],[y(5) y(6)],[z(5) z(6)],'-o');
                    plot3(app.UIAxes,[x(6) x(7)],[y(6) y(7)],[z(6) z(7)],'-x');
                    plot3(app.UIAxes,[x(7) x(8)],[y(7) y(8)],[z(7) z(8)],'-o');
                    

                    if app.Switch_4.Value=="Pokaz pozycje pokolei"
                        hold(app.UIAxes,'off')
                        pause(app.OdstepczasowypomiecykolejnymipozycjamiSpinner.Value/10)
                    end


                end 

            else
                app.InformacjeTextArea.Value =('Wybrana trajektoria ruchu TCP jest niemożliwa do osiagniecia!');            

                hold(app.UIAxes,'off')
                break;
            end


                %tworzenie tabeli z wspolrzednymi maszynowymi
                fi1 = fi1(1,1)
                fi2 = fi2(1,1)
                fi3 = fi3(1,1)
                fi4 = fi4(1,1)
                fi5 = fi5(1,1)
                
                tab(0*total_points + i) = fi1
                tab(1*total_points +1 + i) = fi2
                tab(2*total_points +2 + i) = fi3
                tab(3*total_points +3 + i) = fi4
                tab(4*total_points +4 + i) = fi5
                
                app.UITable.Data = tab    
                %tab - tabela ze wspolrzednymi maszynowymi


                %warunek przerwania wyliczania wsp. maszynowych
                if i == total_points+1
                    break
                end
                xt = xt + x_diff
                yt = yt + y_diff
                zt = zt + z_diff            
            end


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: DODAJButton
        function DODAJButtonPushed(app, event)
            if app.XEditField_3.Value ~= 0 || app.YEditField_3.Value ~= 0 || app.ZEditField_3.Value ~= 0 
                app.extra_pts = app.extra_pts + 1;
                if app.extra_pts > 5 
                    app.extra_pts = 5
                    app.InformacjeTextArea.Value = "Nie można wprowadzić większej ilości punktów: liczba punktów 5/5"
                    return
                end
                epx = app.XEditField_3.Value
                epy = app.YEditField_3.Value
                epz = app.ZEditField_3.Value
                app.tab2(app.extra_pts, 1) = app.extra_pts
                app.tab2(app.extra_pts, 2) = epx
                app.tab2(app.extra_pts, 3) = epy
                app.tab2(app.extra_pts, 4) = epz
                app.UITable2.Data = app.tab2
                app.EditField_6.Value = app.extra_pts+"/5"
            else
                app.InformacjeTextArea.Value = "Nie mozna wstawic punktu[0,0,0]"
            end
            
        end

        % Button pushed function: USUButton
        function USUButtonPushed(app, event)
            app.tab3 = 0
            delete = app.Spinner_2.Value
            if delete > app.extra_pts | delete < 0 | (delete ~= 1 & delete ~= 2 & delete ~= 3 & delete ~= 4 & delete ~= 5)  
                app.InformacjeTextArea.Value = "Taki punkt nie istnieje"
                app.extra_pts
            else
                app.InformacjeTextArea.Value = "Halo 1"
                for i = 1:app.extra_pts
                    app.InformacjeTextArea.Value = "Halo "+ i
                    if delete == app.extra_pts
                        if i == app.extra_pts
                            break
                        end
                        app.tab3(i, 1) = app.tab2(i, 1)
                        app.tab3(i, 2) = app.tab2(i, 2)
                        app.tab3(i, 3) = app.tab2(i, 3)
                        app.tab3(i, 4) = app.tab2(i, 4) 
                    else
                        if delete > i
                            app.tab3(i, 1) = app.tab2(i, 1)
                            app.tab3(i, 2) = app.tab2(i, 2)
                            app.tab3(i, 3) = app.tab2(i, 3)
                            app.tab3(i, 4) = app.tab2(i, 4)
                        elseif delete == i
                            continue
                        else
                            app.tab3(i-1, 1) = app.tab2(i-1, 1)
                            app.tab3(i-1, 2) = app.tab2(i, 2)
                            app.tab3(i-1, 3) = app.tab2(i, 3)
                            app.tab3(i-1, 4) = app.tab2(i, 4)
                        end
                    
                        
                    end
                    
                end
                
                app.extra_pts = app.extra_pts -1;
                app.EditField_6.Value = app.extra_pts+"/5"
                
                app.UITable2.Data = app.tab3;
                app.tab2 = app.tab3
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '3D';
            app.UIAxes = uiaxes(app.DTab);
            title(app.UIAxes, '3D VIEW')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.WSPMASZYNOWETab = uitab(app.TabGroup);
            app.WSPMASZYNOWETab.Title = 'WSP. MASZYNOWE';
            app.UITable = uitable(app.WSPMASZYNOWETab);
            app.UITable.ColumnName = {'fi1'; 'fi2'; 'fi3'; 'fi4'; 'fi5'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UIAxes2 = uiaxes(app.WSPMASZYNOWETab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditField.Value = 100;
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditField.Value = 800;
            app.L3EditFieldLabel = uilabel(app.UIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.UIFigure, 'numeric');
            app.L3EditField.Value = 700;
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.UIFigure, 'numeric');
            app.eEditField.Value = 15;
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.Value = 25;
            app.L4EditFieldLabel = uilabel(app.UIFigure);
            app.L4EditFieldLabel.Text = 'L4';
            app.L4EditField = uieditfield(app.UIFigure, 'numeric');
            app.L4EditField.Value = 150;
            app.L5EditFieldLabel = uilabel(app.UIFigure);
            app.L5EditFieldLabel.Text = 'L5';
            app.L5EditField = uieditfield(app.UIFigure, 'numeric');
            app.L5EditField.Value = 40;
            app.L6EditFieldLabel = uilabel(app.UIFigure);
            app.L6EditFieldLabel.Text = 'L6';
            app.L6EditField = uieditfield(app.UIFigure, 'numeric');
            app.L6EditField.Value = 25;
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'GEOMETRIA ROBOTA';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'WSP. POCZĄTKOWE';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'WSP. KOŃCOWE';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Value = 400;
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Value = 400;
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.Value = 400;
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.Value = 600;
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2.Value = 650;
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = 'KĄTY WEKTORA PODEJŚCIA';
            app.stEditField_2Label = uilabel(app.UIFigure);
            app.stEditField_2Label.Text = 'Θ [st]';
            app.stEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.stEditField_2.Value = 30;
            app.stEditFieldLabel = uilabel(app.UIFigure);
            app.stEditFieldLabel.Text = 'Ψ [st]';
            app.stEditField = uieditfield(app.UIFigure, 'numeric');
            app.stEditField.Value = 40;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'δ1';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'-1', '1'};
            app.Switch.Value = '-1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'δ5';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'-1', '1'};
            app.Switch_2.Value = '-1';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'δ2';
            app.Switch_3 = uiswitch(app.UIFigure, 'slider');
            app.Switch_3.Items = {'-1', '1'};
            app.Switch_3.Value = '-1';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Value = 'ILOŚĆ PUNKTÓW PODPOROWYCH';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Value = 5;
            app.ROZPOCZNIJButton = uibutton(app.UIFigure, 'state');
            app.ROZPOCZNIJButton.ValueChangedFcn = createCallbackFcn(app, @ROZPOCZNIJButtonValueChanged, true);
            app.ROZPOCZNIJButton.Text = 'ROZPOCZNIJ';
            app.STANLampLabel = uilabel(app.UIFigure);
            app.STANLampLabel.Text = 'STAN';
            app.STANLamp = uilamp(app.UIFigure);
            app.DODAWANIEPUNKTWPOREDNICHPanel = uipanel(app.UIFigure);
            app.DODAWANIEPUNKTWPOREDNICHPanel.Title = 'DODAWANIE PUNKTÓW POREDNICH';
            app.XEditField_3Label = uilabel(app.DODAWANIEPUNKTWPOREDNICHPanel);
            app.XEditField_3Label.Text = 'X';
            app.XEditField_3 = uieditfield(app.DODAWANIEPUNKTWPOREDNICHPanel, 'numeric');
            app.YEditField_3Label = uilabel(app.DODAWANIEPUNKTWPOREDNICHPanel);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.DODAWANIEPUNKTWPOREDNICHPanel, 'numeric');
            app.ZEditField_3Label = uilabel(app.DODAWANIEPUNKTWPOREDNICHPanel);
            app.ZEditField_3Label.Text = 'Z';
            app.ZEditField_3 = uieditfield(app.DODAWANIEPUNKTWPOREDNICHPanel, 'numeric');
            app.EditField_6 = uieditfield(app.DODAWANIEPUNKTWPOREDNICHPanel, 'text');
            app.EditField_6.Value = '0/5';
            app.DODAJButton = uibutton(app.DODAWANIEPUNKTWPOREDNICHPanel, 'push');
            app.DODAJButton.ButtonPushedFcn = createCallbackFcn(app, @DODAJButtonPushed, true);
            app.DODAJButton.Text = 'DODAJ';
            app.USUButton = uibutton(app.DODAWANIEPUNKTWPOREDNICHPanel, 'push');
            app.USUButton.ButtonPushedFcn = createCallbackFcn(app, @USUButtonPushed, true);
            app.USUButton.Text = 'USUŃ';
            app.Spinner_2 = uispinner(app.DODAWANIEPUNKTWPOREDNICHPanel);
            app.EditField_8 = uieditfield(app.DODAWANIEPUNKTWPOREDNICHPanel, 'text');
            app.EditField_8.Value = 'PKT DO USUNIĘCIA';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'nr'; 'X'; 'Y'; 'Z'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_7.Value = 'PUNKTY POŚREDNIE';
            app.InformacjeTextAreaLabel = uilabel(app.UIFigure);
            app.InformacjeTextAreaLabel.Text = 'Informacje';
            app.InformacjeTextArea = uitextarea(app.UIFigure);
            app.Switch_4 = uiswitch(app.UIFigure, 'slider');
            app.Switch_4.Items = {'Pokaz wszystkie pozycje', 'Pokaz pozycje pokolei'};
            app.Switch_4.Value = 'Pokaz wszystkie pozycje';
            app.OdstepczasowypomiecykolejnymipozycjamiSpinnerLabel = uilabel(app.UIFigure);
            app.OdstepczasowypomiecykolejnymipozycjamiSpinnerLabel.Text = 'Odstep czasowy pomiecy kolejnymi pozycjami';
            app.OdstepczasowypomiecykolejnymipozycjamiSpinner = uispinner(app.UIFigure);
            app.CzypokazatrajektorieSwitchLabel = uilabel(app.UIFigure);
            app.CzypokazatrajektorieSwitchLabel.Text = 'Czy pokazać trajektorie';
            app.CzypokazatrajektorieSwitch = uiswitch(app.UIFigure, 'slider');
            app.CzypokazatrajektorieSwitch.Items = {'Nie', 'Tak'};
            app.CzypokazatrajektorieSwitch.Value = 'Nie';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZBR_Pawel_Lekan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end